classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        PlayingModeButton      matlab.ui.control.Button
        ResetButton            matlab.ui.control.StateButton
        q7Spinner              matlab.ui.control.Spinner
        q7SpinnerLabel         matlab.ui.control.Label
        q6Spinner              matlab.ui.control.Spinner
        q6SpinnerLabel         matlab.ui.control.Label
        q5Spinner              matlab.ui.control.Spinner
        q5SpinnerLabel         matlab.ui.control.Label
        q4Spinner              matlab.ui.control.Spinner
        q4SpinnerLabel         matlab.ui.control.Label
        q3Spinner              matlab.ui.control.Spinner
        q3SpinnerLabel         matlab.ui.control.Label
        q2Spinner              matlab.ui.control.Spinner
        q2SpinnerLabel         matlab.ui.control.Label
        StateLamp              matlab.ui.control.Lamp
        StateLampLabel         matlab.ui.control.Label
        q1Spinner              matlab.ui.control.Spinner
        q1SpinnerLabel         matlab.ui.control.Label
        ZEditField             matlab.ui.control.NumericEditField
        ZEditFieldLabel        matlab.ui.control.Label
        YEditField             matlab.ui.control.NumericEditField
        YEditFieldLabel        matlab.ui.control.Label
        XEditField             matlab.ui.control.NumericEditField
        XEditFieldLabel        matlab.ui.control.Label
        YawSlider              matlab.ui.control.Slider
        YawSliderLabel         matlab.ui.control.Label
        PitchSlider            matlab.ui.control.Slider
        PitchSliderLabel       matlab.ui.control.Label
        RollSlider             matlab.ui.control.Slider
        RollSliderLabel        matlab.ui.control.Label
        ZSlider                matlab.ui.control.Slider
        ZSliderLabel           matlab.ui.control.Label
        YSlider                matlab.ui.control.Slider
        YSliderLabel           matlab.ui.control.Label
        XSlider                matlab.ui.control.Slider
        XSliderLabel           matlab.ui.control.Label
        MovetoXYZpostionLabel  matlab.ui.control.Label
        Button                 matlab.ui.control.StateButton
        MoveButton             matlab.ui.control.StateButton
        Image                  matlab.ui.control.Image
    end

    
    properties (Access = public)

        robot = RacketBot(transl(-1,1.5,0.5));
        % Description
    end
    
    properties (Access = private)
        counter = 0
        q0 = [0, 0, -pi/2, pi/3, pi/3, pi, 0];
        qTraj  
              % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


        end

        % Value changing function: XSlider
        function XSliderValueChanging(app, event)
            changingValue = event.Value;
            xChange = app.XSlider.Value;
            yChange = app.YSlider.Value;
            zChange = app.ZSlider.Value;
            qCurrent = r.model.getpos();

            T = app.robot.model.fkineUTS(qCurrent);

            app.CurrentXEditField = num2str(T(1,4));
            app.CurrentYEditField = num2str(T(2,4));
            app.CurrentZEditField = num2str(T(3,4));

            pos = [xChange yChange zChange];
            tr = transl(pos);
            qEnd = app.robot.model.ikcon(tr);
            app.robot.model.animate(qEnd);

            pause(0.01);

        end

        % Callback function
        function UIAxesButtonDown(app, event)
            %app.UIFigure = r;
            %app.UIAxes = app.PlotEnvironment;
        end

        % Value changed function: MoveButton
        function MoveButtonValueChanged(app, event)
            value = app.MoveButton.Value;
            assignin("base","button",app.Button.Value)
            x = app.XEditField.Value;
            y = app.YEditField.Value;
            z = app.ZEditField.Value;

            pos = [x y z];
            tr = transl(pos);

            qCurrent = app.robot.model.getpos();

            T = app.robot.model.fkineUTS(qCurrent);


            qEnd = app.robot.model.ikcon(tr);

            app.qTraj = jtraj(qCurrent,qEnd,100);
       
            app.counter = 0;
            for i = 1:100
                
                 
                q = app.qTraj(i,:);
                app.robot.model.animate(q);
                pause(0.02);
                app.counter = app.counter + 1;
                if app.Button.Value == 1
                    
                    break
                    disp(app.Button.Value)
                end
                disp(app.Button.Value)
                app.Button.Value == 0;
              
            end

              
        end

        % Value changed function: Button
        function ButtonValueChanged2(app, event)
            value = app.Button.Value;
            
        end

        % Value changing function: YSlider
        function YSliderValueChanging(app, event)
            changingValue = event.Value;

            
        end

        % Value changed function: YSlider
        function YSliderValueChanged(app, event)
            value = app.YSlider.Value;
            
        end

        % Value changing function: ZSlider
        function ZSliderValueChanging(app, event)
            changingValue = event.Value;
 
        end

        % Callback function
        function ButtonValueChanged(app, event)

            
        end

        % Callback function
        function VisualizepathButtonPushed(app, event)


        end

        % Value changing function: q1Spinner
        function q1SpinnerValueChanging(app, event)
            changingValue = event.Value;            
            qCurrent = app.robot.model.getpos();
            q = [app.q1Spinner.Value, qCurrent(1,2),qCurrent(1,3),qCurrent(1,4),qCurrent(1,5),qCurrent(1,6),qCurrent(1,7)];
            app.robot.model.animate(q);
        end

        % Value changing function: q2Spinner
        function q2SpinnerValueChanging(app, event)
            changingValue = event.Value;
            qCurrent = app.robot.model.getpos();
            q = [qCurrent(1,1), deg2rad(app.q2Spinner.Value),qCurrent(1,3),qCurrent(1,4),qCurrent(1,5),qCurrent(1,6),qCurrent(1,7)];
            app.robot.model.animate(q);
        end

        % Value changing function: q3Spinner
        function q3SpinnerValueChanging(app, event)
            changingValue = event.Value;
            qCurrent = app.robot.model.getpos();
            q = [qCurrent(1,1), qCurrent(1,2), deg2rad(app.q3Spinner.Value),qCurrent(1,4),qCurrent(1,5),qCurrent(1,6),qCurrent(1,7)];
            app.robot.model.animate(q);
            
        end

        % Value changing function: q4Spinner
        function q4SpinnerValueChanging(app, event)
            changingValue = event.Value;
            qCurrent = app.robot.model.getpos();
            q = [qCurrent(1,1), qCurrent(1,2),qCurrent(1,3),deg2rad(app.q4Spinner.Value),qCurrent(1,5),qCurrent(1,6),qCurrent(1,7)];
            app.robot.model.animate(q);

        end

        % Value changing function: q5Spinner
        function q5SpinnerValueChanging(app, event)
            changingValue = event.Value;
            qCurrent = app.robot.model.getpos();
            q = [qCurrent(1,1), qCurrent(1,2),qCurrent(1,3),qCurrent(1,4),deg2rad(app.q5Spinner.Value),qCurrent(1,6),qCurrent(1,7)];
            app.robot.model.animate(q);            
            
        end

        % Value changing function: q6Spinner
        function q6SpinnerValueChanging(app, event)
            changingValue = event.Value;
            qCurrent = app.robot.model.getpos();
            q = [qCurrent(1,1), qCurrent(1,2),qCurrent(1,3),qCurrent(1,4),qCurrent(1,5),deg2rad(app.q6Spinner.Value),qCurrent(1,7)];
            app.robot.model.animate(q);            
        end

        % Value changing function: q7Spinner
        function q7SpinnerValueChanging(app, event)
            changingValue = event.Value;
            qCurrent = app.robot.model.getpos();
            q = [qCurrent(1,1), qCurrent(1,2),qCurrent(1,3),qCurrent(1,4),qCurrent(1,5),qCurrent(1,6),deg2rad(app.q7Spinner.Value)];
            app.robot.model.animate(q);            
        end

        % Callback function
        function ResumeButtonValueChanged(app, event)


        end

        % Value changed function: ResetButton
        function ResetButtonValueChanged(app, event)
            value = app.ResetButton.Value;
                   
        
            for i = app.counter:-1:1
                 
                q = app.qTraj(i,:);
                app.robot.model.animate(q);
                pause(0.01);
               
                % if app.Button.Value == 1
                %     break
                % end
                % app.Button.Value == 0;
              
            end            
        end

        % Button pushed function: PlayingModeButton
        function PlayingModeButtonPushed(app, event)
            PlayingMode
            app.delete
        end

        % Callback function
        function LaunchingButtonPushed(app, event)
            assignin("base","r",app.robot)
            assignin("base","q0",app.q0)
            app.robot.model.animate(app.q0)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'tu-van-chon-vot-cau-long-yonex-nghi-rang.jpg');
            app.MoveButton = uibutton(app.UIFigure, 'state');
            app.MoveButton.ValueChangedFcn = createCallbackFcn(app, @MoveButtonValueChanged, true);
            app.MoveButton.Text = 'Move ';
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged2, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'stopButton.jpg');
            app.Button.Text = '';
            app.MovetoXYZpostionLabel = uilabel(app.UIFigure);
            app.MovetoXYZpostionLabel.Text = 'Move to X, Y, Z postion ';
            app.XSliderLabel = uilabel(app.UIFigure);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.UIFigure);
            app.XSlider.Limits = [0 0.5];
            app.XSlider.ValueChangingFcn = createCallbackFcn(app, @XSliderValueChanging, true);
            app.XSlider.MinorTicks = [0 0.02 0.04 0.06 0.08 0.1 0.12 0.14 0.16 0.18 0.2 0.22 0.24 0.26 0.28 0.3 0.32 0.34 0.36 0.38 0.4 0.42 0.44 0.46 0.48 0.5];
            app.YSliderLabel = uilabel(app.UIFigure);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.UIFigure);
            app.YSlider.Limits = [-0.5 0.5];
            app.YSlider.ValueChangedFcn = createCallbackFcn(app, @YSliderValueChanged, true);
            app.YSlider.ValueChangingFcn = createCallbackFcn(app, @YSliderValueChanging, true);
            app.ZSliderLabel = uilabel(app.UIFigure);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.UIFigure);
            app.ZSlider.Limits = [0 0.5];
            app.ZSlider.ValueChangingFcn = createCallbackFcn(app, @ZSliderValueChanging, true);
            app.RollSliderLabel = uilabel(app.UIFigure);
            app.RollSliderLabel.Text = 'Roll';
            app.RollSlider = uislider(app.UIFigure);
            app.PitchSliderLabel = uilabel(app.UIFigure);
            app.PitchSliderLabel.Text = 'Pitch';
            app.PitchSlider = uislider(app.UIFigure);
            app.YawSliderLabel = uilabel(app.UIFigure);
            app.YawSliderLabel.Text = 'Yaw';
            app.YawSlider = uislider(app.UIFigure);
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.q1SpinnerLabel = uilabel(app.UIFigure);
            app.q1SpinnerLabel.Text = 'q1';
            app.q1Spinner = uispinner(app.UIFigure);
            app.q1Spinner.Step = 0.1;
            app.q1Spinner.ValueChangingFcn = createCallbackFcn(app, @q1SpinnerValueChanging, true);
            app.q1Spinner.Limits = [-2 0.5];
            app.StateLampLabel = uilabel(app.UIFigure);
            app.StateLampLabel.Text = 'State';
            app.StateLamp = uilamp(app.UIFigure);
            app.q2SpinnerLabel = uilabel(app.UIFigure);
            app.q2SpinnerLabel.Text = 'q2';
            app.q2Spinner = uispinner(app.UIFigure);
            app.q2Spinner.Step = 5;
            app.q2Spinner.ValueChangingFcn = createCallbackFcn(app, @q2SpinnerValueChanging, true);
            app.q2Spinner.Limits = [-360 360];
            app.q3SpinnerLabel = uilabel(app.UIFigure);
            app.q3SpinnerLabel.Text = 'q3';
            app.q3Spinner = uispinner(app.UIFigure);
            app.q3Spinner.Step = 5;
            app.q3Spinner.ValueChangingFcn = createCallbackFcn(app, @q3SpinnerValueChanging, true);
            app.q3Spinner.Limits = [-60 50];
            app.q4SpinnerLabel = uilabel(app.UIFigure);
            app.q4SpinnerLabel.Text = 'q4';
            app.q4Spinner = uispinner(app.UIFigure);
            app.q4Spinner.Step = 5;
            app.q4Spinner.ValueChangingFcn = createCallbackFcn(app, @q4SpinnerValueChanging, true);
            app.q4Spinner.Limits = [-90 30];
            app.q5SpinnerLabel = uilabel(app.UIFigure);
            app.q5SpinnerLabel.Text = 'q5';
            app.q5Spinner = uispinner(app.UIFigure);
            app.q5Spinner.Step = 5;
            app.q5Spinner.ValueChangingFcn = createCallbackFcn(app, @q5SpinnerValueChanging, true);
            app.q5Spinner.Limits = [-180 60];
            app.q6SpinnerLabel = uilabel(app.UIFigure);
            app.q6SpinnerLabel.Text = 'q6';
            app.q6Spinner = uispinner(app.UIFigure);
            app.q6Spinner.Step = 5;
            app.q6Spinner.ValueChangingFcn = createCallbackFcn(app, @q6SpinnerValueChanging, true);
            app.q6Spinner.Limits = [-90 90];
            app.q7SpinnerLabel = uilabel(app.UIFigure);
            app.q7SpinnerLabel.Text = 'q7';
            app.q7Spinner = uispinner(app.UIFigure);
            app.q7Spinner.Step = 5;
            app.q7Spinner.ValueChangingFcn = createCallbackFcn(app, @q7SpinnerValueChanging, true);
            app.q7Spinner.Limits = [-360 360];
            app.ResetButton = uibutton(app.UIFigure, 'state');
            app.ResetButton.ValueChangedFcn = createCallbackFcn(app, @ResetButtonValueChanged, true);
            app.ResetButton.Text = 'Reset';
            app.PlayingModeButton = uibutton(app.UIFigure, 'push');
            app.PlayingModeButton.ButtonPushedFcn = createCallbackFcn(app, @PlayingModeButtonPushed, true);
            app.PlayingModeButton.Text = 'Playing Mode';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end